classdef decorr2d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        ImagingPanel                   matlab.ui.container.Panel
        SigmaEditField_2               matlab.ui.control.NumericEditField
        SigmaEditField_2Label          matlab.ui.control.Label
        ScaleEditField                 matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        FrameRateEditField             matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel        matlab.ui.control.Label
        ElevationAngleEditField        matlab.ui.control.NumericEditField
        ElevationAngleEditFieldLabel   matlab.ui.control.Label
        AzimuthAngleEditField          matlab.ui.control.NumericEditField
        AzimuthAngleEditFieldLabel     matlab.ui.control.Label
        DepthEditField                 matlab.ui.control.NumericEditField
        DepthEditFieldLabel            matlab.ui.control.Label
        SetParametersButton            matlab.ui.control.Button
        InitPanel                      matlab.ui.container.Panel
        LoadDataButton                 matlab.ui.control.Button
        DynRangeSlider                 matlab.ui.control.Slider
        DynRangeSliderLabel            matlab.ui.control.Label
        ROIPanel                       matlab.ui.container.Panel
        SetROIButton                   matlab.ui.control.Button
        ElevationCenterEditField       matlab.ui.control.NumericEditField
        ElevationCenterEditFieldLabel  matlab.ui.control.Label
        AzimuthCenterEditField         matlab.ui.control.NumericEditField
        AzimuthCenterEditFieldLabel    matlab.ui.control.Label
        DepthCenterEditField           matlab.ui.control.NumericEditField
        DepthCenterEditFieldLabel      matlab.ui.control.Label
        ROIRadiusEditField             matlab.ui.control.NumericEditField
        ROIRadiusEditFieldLabel        matlab.ui.control.Label
        Setup_tabgroup                 matlab.ui.container.TabGroup
        DTab                           matlab.ui.container.Tab
        deaz_init_2d_axis              matlab.ui.control.UIAxes
        biplaneTab                     matlab.ui.container.Tab
        deaz_init_biplane_axis         matlab.ui.control.UIAxes
        deel_init_biplane_axis         matlab.ui.control.UIAxes
        SettingsPanel                  matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        SerialPanel                    matlab.ui.container.Panel
        DataFolderPanel                matlab.ui.container.Panel
        SelectFolderButton             matlab.ui.control.Button
        ModeButtonGroup                matlab.ui.container.ButtonGroup
        biplaneButton                  matlab.ui.control.RadioButton
        DButton                        matlab.ui.control.RadioButton
        ImagingTab                     matlab.ui.container.Tab
        DynRangeSlider_2               matlab.ui.control.Slider
        DynRangeSlider_2Label          matlab.ui.control.Label
        Maxlog10DecEditField           matlab.ui.control.NumericEditField
        Maxlog10DecEditFieldLabel      matlab.ui.control.Label
        Minlog10DecEditField           matlab.ui.control.NumericEditField
        Minlog10DecEditFieldLabel      matlab.ui.control.Label
        ImagingPanel_2                 matlab.ui.container.Panel
        NoiseCompensationCheckBox      matlab.ui.control.CheckBox
        RecordButton                   matlab.ui.control.StateButton
        CurrentVolumeEditField         matlab.ui.control.NumericEditField
        CurrentVolumeEditFieldLabel    matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        Imaging_tabgroup               matlab.ui.container.TabGroup
        DTab_2                         matlab.ui.container.Tab
        deaz_imaging_2d_axis           matlab.ui.control.UIAxes
        biplaneTab_2                   matlab.ui.container.Tab
        deel_imaging_biplane_axis      matlab.ui.control.UIAxes
        deaz_imaging_biplane_axis      matlab.ui.control.UIAxes
        UIAxes6_2                      matlab.ui.control.UIAxes
        decorrSeries_axis              matlab.ui.control.UIAxes
        ReviewTab                      matlab.ui.container.Tab
        Ra0EditField                   matlab.ui.control.NumericEditField
        Ra0EditFieldLabel              matlab.ui.control.Label
        El0EditField                   matlab.ui.control.NumericEditField
        El0EditFieldLabel              matlab.ui.control.Label
        Az0EditField                   matlab.ui.control.NumericEditField
        Az0EditFieldLabel              matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        SigmaEditField_3               matlab.ui.control.NumericEditField
        SigmaEditField_3Label          matlab.ui.control.Label
        AdjustDataButton               matlab.ui.control.Button
        ImagingPanel_3                 matlab.ui.container.Panel
        DisplayedDecorrelationButtonGroup  matlab.ui.container.ButtonGroup
        CumulativeButton               matlab.ui.control.RadioButton
        InstantaneousButton            matlab.ui.control.RadioButton
        PreviousButton                 matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        RecordButton_2                 matlab.ui.control.StateButton
        CurrentVolumeEditField_2       matlab.ui.control.NumericEditField
        CurrentVolumeEditField_2Label  matlab.ui.control.Label
        Imaging_tabgroup_2             matlab.ui.container.TabGroup
        DTab_3                         matlab.ui.container.Tab
        deaz_imaging_2d_axis_2         matlab.ui.control.UIAxes
        biplaneTab_3                   matlab.ui.container.Tab
        deel_imaging_biplane_axis_2    matlab.ui.control.UIAxes
        deaz_imaging_biplane_axis_2    matlab.ui.control.UIAxes
        decorrSeries_axis_2            matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Experiment;
        type;
        recordingActive;
        state;
        state1Controls,state2Controls,state3Controls,state4Controls,state5Controls,state6Controls;
        reviewCounter; 
    end
    
    methods (Access = private)
        
        function setExperimentObject(app)
            if app.DButton.Value
                app.type = '2D';
                app.set2DModeObjects()
            else
                app.type = 'biplane';
                app.setBiplaneModeObjects()
            end
            %%% TODO %%% edit field for experiment num
            app.Experiment = ExperimentClass2D(app.type,1);
            
        end
        function setGUIType(app)
            if app.DButton.Value
                app.type = '2D';
                app.set2DGUIType()
            else
                app.type = 'biplane';
                app.setBiplpaneGUIType()
            end
        end
        function set2DGUIType(app)
            
            app.Setup_tabgroup.SelectedTab = app.DTab;
            
            app.Imaging_tabgroup.SelectedTab = app.DTab_2;
        end
        function setBiplpaneGUIType(app)
            
            app.Setup_tabgroup.SelectedTab = app.biplaneTab;
            
            app.Imaging_tabgroup.SelectedTab = app.biplaneTab_2;
        end
        function setGUIStateObjects(app)
            switch app.state
                case 1
                    app.setGUIState_presetup()
                case 2
                    app.setGUIState_objectCreated()
                case 3
                    app.setGUIState_initialized()
                case 4
                    app.setGUIState_imaging()
                case 5
                    app.setGUIState_imagingCompleted()
            end
        end
        function setGUIState(app,val)
            app.state = val; 
            app.setGUIStateObjects()
        end
        function setGUIState_presetup(app)
            set(app.state1Controls,'Enable','on');
            set(app.state2Controls,'Enable','off');
            set(app.state3Controls,'Enable','off');
            set(app.state4Controls,'Enable','off');
            set(app.state5Controls,'Enable','off');
            
        end
        function setGUIState_objectCreated(app)
            set(app.state1Controls,'Enable','off');
            set(app.state2Controls,'Enable','on');
            set(app.state3Controls,'Enable','off');
            set(app.state4Controls,'Enable','off');
            set(app.state5Controls,'Enable','off');
        end
        function setGUIState_initialized(app)
            set(app.state1Controls,'Enable','off');
            set(app.state2Controls,'Enable','off');
            set(app.state3Controls,'Enable','on');
            set(app.state4Controls,'Enable','off');
            set(app.state5Controls,'Enable','off');
        end
        function setGUIState_imaging(app)
            set(app.state1Controls,'Enable','on');
            set(app.state2Controls,'Enable','off');
            set(app.state3Controls,'Enable','off');
            set(app.state4Controls,'Enable','on');
            set(app.state5Controls,'Enable','off');
        end
        function setGUIState_imagingCompleted(app)
            
        end
        function setBiplaneModeObjects(app)
            
            app.Setup_tabgroup.SelectedTab = app.biplaneTab;
        end
        function set2DModeObjects(app)
            
            app.Setup_tabgroup.SelectedTab = app.DTab;
        end
        function displayInit2D(app)
            displayData = app.Experiment.initDataSet.rawData_cart(:,:,1);
            imagesc(app.deaz_init_2d_axis,log10(abs(displayData)));
            colormap(app.deaz_init_2d_axis,'gray')
        end
        function displayInitBiplane(app)
            displayData1 = app.Experiment.initDataSet.rawData_cart{1};
            displayData1 = displayData1(:,:,1);
            displayData2 = app.Experiment.initDataSet.rawData_cart{2};
            displayData2 = displayData2(:,:,1);
            imagesc(app.deaz_init_biplane_axis,log10(abs(displayData1)));
            colormap(app.deaz_init_biplane_axis,'gray')
            imagesc(app.deel_init_biplane_axis,log10(abs(displayData2)));
            colormap(app.deel_init_biplane_axis,'gray')
        end
        function displayImaging2D(app)
            BmodeIn = 20*log10(abs(app.Experiment.dataSeries{end}.rawData_cart(:,:,1)));
            
            bMode_scale = 20*log10(.25);
            if app.NoiseCompensationCheckBox.Value
                decorrIn = log10(app.Experiment.adjCumulativeDecorr);
            else
                decorrIn = log10(app.Experiment.cumulativeDecorr);
            end
            minDecorrVal = app.Minlog10DecEditField.Value;
            maxDecorrVal = app.Maxlog10DecEditField.Value;
            decorrScaled = decorrIn/(maxDecorrVal-minDecorrVal)+1; 
            %decorrScaled(decorrScaled>1) = 1; 
            %decorrScaled(decorrScaled<0) = 0; 
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            dynamicRange = app.DynRangeSlider_2.Value;
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;

            %green = hybrid_RGB(:,:,2);
            %green(overLayImAzel) = 1; 
            %hybrid_RGB(:,:,2) = green; 
            image(app.deaz_imaging_2d_axis,hybrid_RGB);
        end
        function displayImagingBiplane(app)
            BmodeIn = 20*log10(abs(app.Experiment.dataSeries{end}.rawData_cart{1}));
            BmodeIn = BmodeIn(:,:,1);
            bMode_scale = 20*log10(.25);
            if app.NoiseCompensationCheckBox.Value
                decorrIn = log10(app.Experiment.adjCumulativeDecorr{1});
            else
                decorrIn = log10(app.Experiment.cumulativeDecorr{1});
            end
            minDecorrVal = app.Minlog10DecEditField.Value;
            maxDecorrVal = app.Maxlog10DecEditField.Value;
            decorrScaled = decorrIn/(maxDecorrVal-minDecorrVal) + 1; 
            decorrScaled(decorrScaled>1) = 1; 
            decorrScaled(decorrScaled<0) = 0; 
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            dynamicRange = app.DynRangeSlider_2.Value;
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;
            image(app.deaz_imaging_biplane_axis,hybrid_RGB);
            
            BmodeIn = 20*log10(abs(app.Experiment.dataSeries{end}.rawData_cart{2}));
            BmodeIn = BmodeIn(:,:,1);
            bMode_scale = 20*log10(.25);
            if app.NoiseCompensationCheckBox.Value
                decorrIn = log10(app.Experiment.adjCumulativeDecorr{2});
            else
                decorrIn = log10(app.Experiment.cumulativeDecorr{2});
            end
            decorrScaled = decorrIn/(maxDecorrVal-minDecorrVal) + 1; 
            decorrScaled(decorrScaled>1) = 1; 
            decorrScaled(decorrScaled<0) = 0; 
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;
            image(app.deel_imaging_biplane_axis,hybrid_RGB);
            drawnow; 
        end
        function displayImaging(app)
            switch app.Experiment.mode
                case '2D'
                    app.displayImaging2D();
                case 'biplane'
                    app.displayImagingBiplane(); 
            end
        end
        function displayInit(app)
            switch app.Experiment.mode
                case '2D'
                    app.displayInit2D();
                case 'biplane'
                    app.displayInitBiplane(); 
            end
        end
        function displayDecorrSeries(app)
            switch app.Experiment.mode
                case '2D'
                    app.displayDecorrSeries2D();
                case 'biplane'
                    app.displayDecorrSeriesBiplane();
            end
        end
        function displayDecorrSeriesBiplane(app)
            plot(app.decorrSeries_axis, log10(app.Experiment.meanDecorrSeries{1}))
            hold(app.decorrSeries_axis, 'on')
            plot(app.decorrSeries_axis, log10(app.Experiment.meanDecorrSeries{2}))
        end
        function displayDecorrSeries2D(app)
            plot(app.decorrSeries_axis, log10(app.Experiment.meanDecorrSeries))
        end
        function displayImagingReviewBiplane(app)
            BmodeIn = 20*log10(abs(app.Experiment.dataSeries{app.reviewCounter}.rawData_cart{1}));
            BmodeIn = BmodeIn(:,:,1);
            bMode_scale = 20*log10(.25);
            if app.NoiseCompensationCheckBox.Value
                decorrIn = log10(app.Experiment.adjCumulativeDecorr{1});
            else
                decorrIn = log10(app.Experiment.cumulativeDecorr{1});
            end
            minDecorrVal = -6;
            maxDecorrVal = -.5;
            decorrScaled = decorrIn/(maxDecorrVal-minDecorrVal) + 1; 
            decorrScaled(decorrScaled>1) = 1; 
            decorrScaled(decorrScaled<0) = 0; 
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            dynamicRange = 100;
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;
            image(app.deaz_imaging_biplane_axis,hybrid_RGB);
            
            BmodeIn = 20*log10(abs(app.Experiment.dataSeries{end}.rawData_cart{2}));
            BmodeIn = BmodeIn(:,:,1);
            bMode_scale = 20*log10(.25);
            if app.NoiseCompensationCheckBox.Value
                decorrIn = log10(app.Experiment.adjCumulativeDecorr{2});
            else
                decorrIn = log10(app.Experiment.cumulativeDecorr{2});
            end
            minDecorrVal = -5;
            maxDecorrVal = 0;
            decorrScaled = decorrIn/(maxDecorrVal-minDecorrVal) + 1; 
            decorrScaled(decorrScaled>1) = 1; 
            decorrScaled(decorrScaled<0) = 0; 
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            dynamicRange = 100;
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;
            image(app.deel_imaging_biplane_axis,hybrid_RGB);
            drawnow; 
        end
        function displayImagingReview2D(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.state1Controls = [app.ModeButtonGroup,app.DataFolderPanel,app.SerialPanel];
            app.state2Controls = [app.ImagingPanel];
            app.state3Controls = [app.InitPanel];
            app.state4Controls = [app.ROIPanel];
            app.state5Controls = [];
            app.setGUIState(1);
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.setExperimentObject()
            app.Experiment.initDataFolderGUI()
            app.setGUIState(2);
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            azAngle = app.AzimuthAngleEditField.Value;
            elAngle = app.ElevationAngleEditField.Value;
            depth = app.DepthEditField.Value;
            frameRate = app.FrameRateEditField.Value;
            cartStep = app.ScaleEditField.Value;
            sigma = app.SigmaEditField_2.Value;
            app.Experiment.setImagingParams(cartStep, sigma)
            app.setGUIState(3);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.Experiment.getInitDataSet()
            app.displayInit();
            app.setGUIState(4);
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            while app.RecordButton.Value
                if app.Experiment.checkNextDataSetReady
                    app.Experiment.getNextDataSet;
                    app.Experiment.processLatestDataSet;
                    app.displayImaging();
                    app.displayDecorrSeries()
                end
                pause(.1)
            end
        end

        % Button pushed function: SetROIButton
        function SetROIButtonPushed(app, event)
            z0 = app.DepthCenterEditField.Value;
            y0 = app.AzimuthCenterEditField.Value;
            x0 = app.ElevationCenterEditField.Value;
            r = app.ROIRadiusEditField.Value;
            app.Experiment.setROIParams(z0,y0,x0,r)
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            app.setGUIType()
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.setGUIState(1)
        end

        % Value changed function: Minlog10DecEditField
        function Minlog10DecEditFieldValueChanged(app, event)
            value = app.Minlog10DecEditField.Value;
            app.displayImaging2D()
        end

        % Value changed function: Maxlog10DecEditField
        function Maxlog10DecEditFieldValueChanged(app, event)
            value = app.Maxlog10DecEditField.Value;
            app.displayImaging2D()
        end

        % Value changed function: DynRangeSlider_2
        function DynRangeSlider_2ValueChanged(app, event)
            value = app.DynRangeSlider_2.Value;
            app.displayImaging2D();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SettingsPanel = uipanel(app.SetupTab);
            app.SettingsPanel.Title = 'Settings';
            app.ModeButtonGroup = uibuttongroup(app.SettingsPanel);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.DButton = uiradiobutton(app.ModeButtonGroup);
            app.DButton.Text = '2D';
            app.DButton.Value = true;
            app.biplaneButton = uiradiobutton(app.ModeButtonGroup);
            app.biplaneButton.Text = 'biplane';
            app.DataFolderPanel = uipanel(app.SettingsPanel);
            app.DataFolderPanel.Title = 'Data Folder';
            app.SelectFolderButton = uibutton(app.DataFolderPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SerialPanel = uipanel(app.SettingsPanel);
            app.SerialPanel.Title = 'Serial';
            app.ResetButton = uibutton(app.SettingsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Setup_tabgroup = uitabgroup(app.SetupTab);
            app.DTab = uitab(app.Setup_tabgroup);
            app.DTab.Title = '2D';
            app.deaz_init_2d_axis = uiaxes(app.DTab);
            title(app.deaz_init_2d_axis, 'Depth Azimuth')
            xlabel(app.deaz_init_2d_axis, 'X')
            ylabel(app.deaz_init_2d_axis, 'Y')
            zlabel(app.deaz_init_2d_axis, 'Z')
            app.biplaneTab = uitab(app.Setup_tabgroup);
            app.biplaneTab.Title = 'biplane';
            app.deel_init_biplane_axis = uiaxes(app.biplaneTab);
            title(app.deel_init_biplane_axis, 'Depth Azimuth')
            xlabel(app.deel_init_biplane_axis, 'X')
            ylabel(app.deel_init_biplane_axis, 'Y')
            zlabel(app.deel_init_biplane_axis, 'Z')
            app.deaz_init_biplane_axis = uiaxes(app.biplaneTab);
            title(app.deaz_init_biplane_axis, 'Depth Elevation')
            xlabel(app.deaz_init_biplane_axis, 'X')
            ylabel(app.deaz_init_biplane_axis, 'Y')
            zlabel(app.deaz_init_biplane_axis, 'Z')
            app.ROIPanel = uipanel(app.SetupTab);
            app.ROIPanel.Title = 'ROI';
            app.ROIRadiusEditFieldLabel = uilabel(app.ROIPanel);
            app.ROIRadiusEditFieldLabel.Text = 'ROI Radius';
            app.ROIRadiusEditField = uieditfield(app.ROIPanel, 'numeric');
            app.DepthCenterEditFieldLabel = uilabel(app.ROIPanel);
            app.DepthCenterEditFieldLabel.Text = 'Depth Center';
            app.DepthCenterEditField = uieditfield(app.ROIPanel, 'numeric');
            app.AzimuthCenterEditFieldLabel = uilabel(app.ROIPanel);
            app.AzimuthCenterEditFieldLabel.Text = 'Azimuth Center';
            app.AzimuthCenterEditField = uieditfield(app.ROIPanel, 'numeric');
            app.ElevationCenterEditFieldLabel = uilabel(app.ROIPanel);
            app.ElevationCenterEditFieldLabel.Text = 'Elevation Center';
            app.ElevationCenterEditField = uieditfield(app.ROIPanel, 'numeric');
            app.SetROIButton = uibutton(app.ROIPanel, 'push');
            app.SetROIButton.ButtonPushedFcn = createCallbackFcn(app, @SetROIButtonPushed, true);
            app.SetROIButton.Text = 'Set ROI';
            app.InitPanel = uipanel(app.SetupTab);
            app.InitPanel.Title = 'Init';
            app.DynRangeSliderLabel = uilabel(app.InitPanel);
            app.DynRangeSliderLabel.Text = 'Dyn. Range';
            app.DynRangeSlider = uislider(app.InitPanel);
            app.LoadDataButton = uibutton(app.InitPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ImagingPanel = uipanel(app.SetupTab);
            app.ImagingPanel.Title = 'Imaging';
            app.SetParametersButton = uibutton(app.ImagingPanel, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.DepthEditFieldLabel = uilabel(app.ImagingPanel);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.AzimuthAngleEditFieldLabel = uilabel(app.ImagingPanel);
            app.AzimuthAngleEditFieldLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.ElevationAngleEditFieldLabel = uilabel(app.ImagingPanel);
            app.ElevationAngleEditFieldLabel.Text = 'Elevation Angle';
            app.ElevationAngleEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.FrameRateEditFieldLabel = uilabel(app.ImagingPanel);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.ScaleEditFieldLabel = uilabel(app.ImagingPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.SigmaEditField_2Label = uilabel(app.ImagingPanel);
            app.SigmaEditField_2Label.Text = 'Sigma';
            app.SigmaEditField_2 = uieditfield(app.ImagingPanel, 'numeric');
            app.ImagingTab = uitab(app.TabGroup);
            app.ImagingTab.Title = 'Imaging';
            app.decorrSeries_axis = uiaxes(app.ImagingTab);
            title(app.decorrSeries_axis, 'Decorrelation')
            xlabel(app.decorrSeries_axis, 'X')
            ylabel(app.decorrSeries_axis, 'Y')
            zlabel(app.decorrSeries_axis, 'Z')
            app.UIAxes6_2 = uiaxes(app.ImagingTab);
            title(app.UIAxes6_2, 'Serial')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.Imaging_tabgroup = uitabgroup(app.ImagingTab);
            app.DTab_2 = uitab(app.Imaging_tabgroup);
            app.DTab_2.Title = '2D';
            app.deaz_imaging_2d_axis = uiaxes(app.DTab_2);
            title(app.deaz_imaging_2d_axis, 'Depth Azimuth')
            xlabel(app.deaz_imaging_2d_axis, 'X')
            ylabel(app.deaz_imaging_2d_axis, 'Y')
            zlabel(app.deaz_imaging_2d_axis, 'Z')
            app.biplaneTab_2 = uitab(app.Imaging_tabgroup);
            app.biplaneTab_2.Title = 'biplane';
            app.deaz_imaging_biplane_axis = uiaxes(app.biplaneTab_2);
            title(app.deaz_imaging_biplane_axis, 'Depth Azimuth')
            xlabel(app.deaz_imaging_biplane_axis, 'X')
            ylabel(app.deaz_imaging_biplane_axis, 'Y')
            zlabel(app.deaz_imaging_biplane_axis, 'Z')
            app.deel_imaging_biplane_axis = uiaxes(app.biplaneTab_2);
            title(app.deel_imaging_biplane_axis, 'Depth Elevation')
            xlabel(app.deel_imaging_biplane_axis, 'X')
            ylabel(app.deel_imaging_biplane_axis, 'Y')
            zlabel(app.deel_imaging_biplane_axis, 'Z')
            app.ImagingPanel_2 = uipanel(app.ImagingTab);
            app.ImagingPanel_2.Title = 'Imaging';
            app.PauseButton = uibutton(app.ImagingPanel_2, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.ImagingPanel_2, 'push');
            app.StopButton.Text = 'Stop';
            app.CurrentVolumeEditFieldLabel = uilabel(app.ImagingPanel_2);
            app.CurrentVolumeEditFieldLabel.Text = 'Current Volume';
            app.CurrentVolumeEditField = uieditfield(app.ImagingPanel_2, 'numeric');
            app.RecordButton = uibutton(app.ImagingPanel_2, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.NoiseCompensationCheckBox = uicheckbox(app.ImagingPanel_2);
            app.NoiseCompensationCheckBox.Text = 'Noise Compensation';
            app.Minlog10DecEditFieldLabel = uilabel(app.ImagingTab);
            app.Minlog10DecEditFieldLabel.Text = 'Min log10 Dec';
            app.Minlog10DecEditField = uieditfield(app.ImagingTab, 'numeric');
            app.Minlog10DecEditField.ValueChangedFcn = createCallbackFcn(app, @Minlog10DecEditFieldValueChanged, true);
            app.Maxlog10DecEditFieldLabel = uilabel(app.ImagingTab);
            app.Maxlog10DecEditFieldLabel.Text = 'Max log10 Dec';
            app.Maxlog10DecEditField = uieditfield(app.ImagingTab, 'numeric');
            app.Maxlog10DecEditField.ValueChangedFcn = createCallbackFcn(app, @Maxlog10DecEditFieldValueChanged, true);
            app.DynRangeSlider_2Label = uilabel(app.ImagingTab);
            app.DynRangeSlider_2Label.Text = 'Dyn. Range';
            app.DynRangeSlider_2 = uislider(app.ImagingTab);
            app.DynRangeSlider_2.ValueChangedFcn = createCallbackFcn(app, @DynRangeSlider_2ValueChanged, true);
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.decorrSeries_axis_2 = uiaxes(app.ReviewTab);
            title(app.decorrSeries_axis_2, 'Decorrelation')
            xlabel(app.decorrSeries_axis_2, 'X')
            ylabel(app.decorrSeries_axis_2, 'Y')
            zlabel(app.decorrSeries_axis_2, 'Z')
            app.Imaging_tabgroup_2 = uitabgroup(app.ReviewTab);
            app.DTab_3 = uitab(app.Imaging_tabgroup_2);
            app.DTab_3.Title = '2D';
            app.deaz_imaging_2d_axis_2 = uiaxes(app.DTab_3);
            title(app.deaz_imaging_2d_axis_2, 'Depth Azimuth')
            xlabel(app.deaz_imaging_2d_axis_2, 'X')
            ylabel(app.deaz_imaging_2d_axis_2, 'Y')
            zlabel(app.deaz_imaging_2d_axis_2, 'Z')
            app.biplaneTab_3 = uitab(app.Imaging_tabgroup_2);
            app.biplaneTab_3.Title = 'biplane';
            app.deaz_imaging_biplane_axis_2 = uiaxes(app.biplaneTab_3);
            title(app.deaz_imaging_biplane_axis_2, 'Depth Azimuth')
            xlabel(app.deaz_imaging_biplane_axis_2, 'X')
            ylabel(app.deaz_imaging_biplane_axis_2, 'Y')
            zlabel(app.deaz_imaging_biplane_axis_2, 'Z')
            app.deel_imaging_biplane_axis_2 = uiaxes(app.biplaneTab_3);
            title(app.deel_imaging_biplane_axis_2, 'Depth Elevation')
            xlabel(app.deel_imaging_biplane_axis_2, 'X')
            ylabel(app.deel_imaging_biplane_axis_2, 'Y')
            zlabel(app.deel_imaging_biplane_axis_2, 'Z')
            app.ImagingPanel_3 = uipanel(app.ReviewTab);
            app.ImagingPanel_3.Title = 'Imaging';
            app.CurrentVolumeEditField_2Label = uilabel(app.ImagingPanel_3);
            app.CurrentVolumeEditField_2Label.Text = 'Current Volume';
            app.CurrentVolumeEditField_2 = uieditfield(app.ImagingPanel_3, 'numeric');
            app.RecordButton_2 = uibutton(app.ImagingPanel_3, 'state');
            app.RecordButton_2.Text = 'Record';
            app.NextButton = uibutton(app.ImagingPanel_3, 'push');
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.ImagingPanel_3, 'push');
            app.PreviousButton.Text = 'Previous';
            app.DisplayedDecorrelationButtonGroup = uibuttongroup(app.ImagingPanel_3);
            app.DisplayedDecorrelationButtonGroup.Title = 'Displayed Decorrelation';
            app.InstantaneousButton = uiradiobutton(app.DisplayedDecorrelationButtonGroup);
            app.InstantaneousButton.Text = 'Instantaneous';
            app.InstantaneousButton.Value = true;
            app.CumulativeButton = uiradiobutton(app.DisplayedDecorrelationButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.AdjustDataButton = uibutton(app.ReviewTab, 'push');
            app.AdjustDataButton.Text = 'Adjust Data';
            app.SigmaEditField_3Label = uilabel(app.ReviewTab);
            app.SigmaEditField_3Label.Text = 'Sigma';
            app.SigmaEditField_3 = uieditfield(app.ReviewTab, 'numeric');
            app.RadiusEditFieldLabel = uilabel(app.ReviewTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.ReviewTab, 'numeric');
            app.Az0EditFieldLabel = uilabel(app.ReviewTab);
            app.Az0EditFieldLabel.Text = 'Az0';
            app.Az0EditField = uieditfield(app.ReviewTab, 'numeric');
            app.El0EditFieldLabel = uilabel(app.ReviewTab);
            app.El0EditFieldLabel.Text = 'El0';
            app.El0EditField = uieditfield(app.ReviewTab, 'numeric');
            app.Ra0EditFieldLabel = uilabel(app.ReviewTab);
            app.Ra0EditFieldLabel.Text = 'Ra0';
            app.Ra0EditField = uieditfield(app.ReviewTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = decorr2d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end